classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KEditField                    matlab.ui.control.EditField
        KEditFieldLabel               matlab.ui.control.Label
        DisturbeButton                matlab.ui.control.Button
        DisturbeySlider               matlab.ui.control.Slider
        DisturbeySliderLabel          matlab.ui.control.Label
        DisturbeuSlider               matlab.ui.control.Slider
        DisturbeuSliderLabel          matlab.ui.control.Label
        QEditField                    matlab.ui.control.NumericEditField
        QEditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        EncodersResetButton           matlab.ui.control.Button
        ControlEffortSignalEditField  matlab.ui.control.NumericEditField
        ControlEffortSignalEditFieldLabel  matlab.ui.control.Label
        UIAxes5_2                     matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        timer1 = timer('Name', 'Apptimer', 'Period', 0.1, 'ExecutionMode', 'fixedSpacing')
        simout % Description
        OUT
        k% Description
    end
    
    methods (Access = public)
        
        function UpdateGUI(app,event)
            app.OUT=evalin("base",'out');
            plot(app.UIAxes,double(squeeze(app.OUT.X_Mat.signal1.Data)));
            plot(app.UIAxes2,double(squeeze(app.OUT.X_Mat.signal2.Data)));
            plot(app.UIAxes3,double(squeeze(app.OUT.X_Mat.theta0_dot.Data)));
            plot(app.UIAxes4,double(squeeze(app.OUT.X_Mat.theta1_dot.Data)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

             run("LQR_updated.m")
             app.timer1.TimerFcn = @(timer_obj, event) app.UpdateGUI(timer_obj, event);
             evalin("base",'K')
             %app.timer1.start;
             start(timer1);
        end

        % Button pushed function: EncodersResetButton
        function EncodersResetButtonPushed(app, event)
            evalin('base', ['Reset = ', num2str(1), '']);
            pause('on')
            pause(0.1)
            pause('off')
            evalin('base', ['Reset = ', num2str(0), '']);
            % app.OUT=evalin("base",'out');
            % plot(app.UIAxes,double(squeeze(app.OUT.X_Mat.signal1.Data)));
            % plot(app.UIAxes2,double(squeeze(app.OUT.X_Mat.signal2.Data)));
            % plot(app.UIAxes3,double(squeeze(app.OUT.X_Mat.theta0_dot.Data)));
            % plot(app.UIAxes4,double(squeeze(app.OUT.X_Mat.theta1_dot.Data)));
        end

        % Button pushed function: DisturbeButton
        function DisturbeButtonPushed(app, event)
            evalin('base', ['uDist = ', num2str(app.DisturbeuSlider.Value), '']);
            evalin('base', ['yDist = ', num2str(app.DisturbeySlider.Value), '']);
            pause('on')
            pause(0.1)
            pause('off')
            evalin('base', ['uDist = ', num2str(0), '']);
            evalin('base', ['yDist = ', num2str(0), '']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'theta0')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'rad')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'theta1')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'rad/s')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'theta0dot')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'rad')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'theta1dot')
            xlabel(app.UIAxes4, 't')
            ylabel(app.UIAxes4, 'rad/s')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Syp')
            xlabel(app.UIAxes5, 'w')
            ylabel(app.UIAxes5, 'sens y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5_2 = uiaxes(app.UIFigure);
            title(app.UIAxes5_2, 'Sup')
            xlabel(app.UIAxes5_2, 'w')
            ylabel(app.UIAxes5_2, 'sens x')
            zlabel(app.UIAxes5_2, 'Z')
            app.ControlEffortSignalEditFieldLabel = uilabel(app.UIFigure);
            app.ControlEffortSignalEditFieldLabel.Text = 'Control Effort Signal';
            app.ControlEffortSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.EncodersResetButton = uibutton(app.UIFigure, 'push');
            app.EncodersResetButton.ButtonPushedFcn = createCallbackFcn(app, @EncodersResetButtonPushed, true);
            app.EncodersResetButton.Text = 'Encoders Reset';
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisturbeuSliderLabel = uilabel(app.UIFigure);
            app.DisturbeuSliderLabel.Text = 'Disturbe u';
            app.DisturbeuSlider = uislider(app.UIFigure);
            app.DisturbeuSlider.Limits = [-10 10];
            app.DisturbeySliderLabel = uilabel(app.UIFigure);
            app.DisturbeySliderLabel.Text = 'Disturbe y';
            app.DisturbeySlider = uislider(app.UIFigure);
            app.DisturbeySlider.Limits = [-50 50];
            app.DisturbeButton = uibutton(app.UIFigure, 'push');
            app.DisturbeButton.ButtonPushedFcn = createCallbackFcn(app, @DisturbeButtonPushed, true);
            app.DisturbeButton.Text = 'Disturbe';
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end